classdef pantallaTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ClasificarOKLabel            matlab.ui.control.Label
        CLASIFICARDATOSButton        matlab.ui.control.Button
        SegundoPasoLabel             matlab.ui.control.Label
        PrimerPasoLabel              matlab.ui.control.Label
        PanelCargarDatosClasif       matlab.ui.container.Panel
        MostrarlosdatosButton        matlab.ui.control.Button
        DatosPredetListBox           matlab.ui.control.ListBox
        RedespredeterminadasLabel_2  matlab.ui.control.Label
        LabelDatosRuta               matlab.ui.control.Label
        ArchivoDatosLabel            matlab.ui.control.Label
        ExplorarDatosButton          matlab.ui.control.Button
        PanelCargarRed               matlab.ui.container.Panel
        RedesPredetListBox           matlab.ui.control.ListBox
        RedespredeterminadasLabel    matlab.ui.control.Label
        LabelRedesRuta               matlab.ui.control.Label
        ArchivoRedesLabel            matlab.ui.control.Label
        ExplorarRedesButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        pantallaResultados % Description
        Red
        DatosTest % Description
        YPred
        Acc
        YT
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ExplorarRedesButton
        function ExplorarRedesButtonPushed(app, event)
            %[filename, filepath] = uigetfile(filter, dialogTitle);
            %filter es para el tipo de archivo como '*.txt'
            [filename, ~] = uigetfile('*.*', 'Selecciona un archivo'); 

            % Comprueba si el usuario ha seleccionado un archivo
            if isequal(filename, 0) %compara 2 vars. A y B
                app.LabelRedesRuta.Text = 'No se ha seleccionado ningún archivo.';
                app.RedesPredetListBox.Value = '-';
            else
                %app.NosehaseleccionadoningnarchivoLabel.Text = ['Archivo seleccionado: ', fullfile(filepath, filename)];
                app.LabelRedesRuta.Text = filename;
                app.RedesPredetListBox.Value = '-';
                %%hay que cargar la red
                %app.Red = load(filename);
                app.Red = load('red_ejemplo_entrenada.mat');
                app.Red
                %load filename;
            end
        end

        % Value changed function: RedesPredetListBox
        function RedesPredetListBoxValueChanged(app, event)
            value = app.RedesPredetListBox.Value;
            if isequal(value, '-') %si se elige '-'
                app.LabelRedesRuta.Text = 'No se ha seleccionado ningún archivo.';
            else
                app.LabelRedesRuta.Text = value + " cargada.";
                %%hay que cargar la red
                app.Red = load(value); %%da error si cargamos una red que no existe
            end
        end

        % Button pushed function: ExplorarDatosButton
        function ExplorarDatosButtonPushed(app, event)
             %[filename, filepath] = uigetfile(filter, dialogTitle);
            %filter es para el tipo de archivo como '*.txt'
            [filename, ~] = uigetfile('*.*', 'Selecciona un archivo'); 

            % Comprueba si el usuario ha seleccionado un archivo
            if isequal(filename, 0) %compara 2 vars. A y B
                app.LabelDatosRuta.Text = 'No se ha seleccionado ningún archivo.';
                app.DatosPredetListBox.Value = '-';
                app.MostrarlosdatosButton.Enable = "off";
            else
                %app.NosehaseleccionadoningnarchivoLabel.Text = ['Archivo seleccionado: ', fullfile(filepath, filename)];
                app.LabelDatosRuta.Text = filename;
                app.DatosPredetListBox.Value = '-';
                app.MostrarlosdatosButton.Enable = "on";
                %%cargar los datos,
                
                %%SE PODRIA PONER UN CATCH PARA EXCEPCION SI NO SE CARGA
                app.DatosTest = load(filename, 'XTest', 'YTest');
            end
        end

        % Value changed function: DatosPredetListBox
        function DatosPredetListBoxValueChanged(app, event)
            value = app.DatosPredetListBox.Value;
            if isequal(value, '-') %si se elige '-'
                app.LabelDatosRuta.Text = 'No se ha seleccionado ningún archivo.';
                app.MostrarlosdatosButton.Enable = "off";
            else
                app.LabelDatosRuta.Text = value + " cargado.";
                app.MostrarlosdatosButton.Enable = "on";
                %cargar los datos
                app.DatosTest = load(value, 'XTest', 'YTest');
            end
        end

        % Button pushed function: CLASIFICARDATOSButton
        function CLASIFICARDATOSButtonPushed(app, event)

            if isequal(app.RedesPredetListBox.Value, '-')
                app.ClasificarOKLabel.Text = 'Selecciona correctamente la red.';
                %%nada
            else
                if isequal(app.DatosPredetListBox.Value, '-')
                    app.ClasificarOKLabel.Text = 'Selecciona correctamente los datos.';
                    %%nada
                else
                    app.ClasificarOKLabel.Text = 'CARGANDO NUEVA PANTALLA...';
                    %%NUEVA PANTALLA DE CLASIF DATOS, pero primero
                    %%clasificamos y calculamos accuracy
                    app.YPred = classify(app.Red.net, app.DatosTest.XTest{1});
                    %acc = sum(YPred == YTest{1})./numel(YTest{1})
                    suma = sum(app.YPred == app.DatosTest.YTest{1})./numel(app.DatosTest.YTest{1});
                    app.Acc = suma; %%de aqui sale bien el valor 

                    app.UIFigure.Visible = 'off';
                    app.pantallaResultados = pantallaResultados();
                    app.pantallaResultados.YT = app.DatosTest.YTest{1};
                    app.pantallaResultados.YPred = app.YPred;
                    app.pantallaResultados.Acc = app.Acc;
                    app.pantallaResultados.UIFigure.Visible = 'on';                    
                end
            end
            
        end

        % Button pushed function: MostrarlosdatosButton
        function MostrarlosdatosButtonPushed(app, event)
            numFeatures = 3;  %%suponemos que esto es 3 siempre
            figure
            plot(app.DatosTest.XTest{1}')
            xlabel("Paso de tiempo")
            legend("Coordenada " + (1:numFeatures))
            title("Datos a clasificar")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelCargarRed = uipanel(app.UIFigure);
            app.ExplorarRedesButton = uibutton(app.PanelCargarRed, 'push');
            app.ExplorarRedesButton.ButtonPushedFcn = createCallbackFcn(app, @ExplorarRedesButtonPushed, true);
            app.ExplorarRedesButton.Text = 'Cargar red previamente entrenada';
            app.ArchivoRedesLabel = uilabel(app.PanelCargarRed);
            app.ArchivoRedesLabel.Text = 'Archivo:';
            app.LabelRedesRuta = uilabel(app.PanelCargarRed);
            app.LabelRedesRuta.Text = 'No se ha seleccionado ningún archivo.';
            app.RedespredeterminadasLabel = uilabel(app.PanelCargarRed);
            app.RedespredeterminadasLabel.Text = {'Redes        '; 'predeterminadas'};
            app.RedesPredetListBox = uilistbox(app.PanelCargarRed);
            app.RedesPredetListBox.Items = {'-', 'red_ejemplo_entrenada.mat', 'red_piribiri.mat', 'nikeNetwork.mat'};
            app.RedesPredetListBox.ValueChangedFcn = createCallbackFcn(app, @RedesPredetListBoxValueChanged, true);
            app.RedesPredetListBox.Value = '-';
            app.PanelCargarDatosClasif = uipanel(app.UIFigure);
            app.ExplorarDatosButton = uibutton(app.PanelCargarDatosClasif, 'push');
            app.ExplorarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @ExplorarDatosButtonPushed, true);
            app.ExplorarDatosButton.Text = 'Cargar datos para clasificar';
            app.ArchivoDatosLabel = uilabel(app.PanelCargarDatosClasif);
            app.ArchivoDatosLabel.Text = 'Archivo:';
            app.LabelDatosRuta = uilabel(app.PanelCargarDatosClasif);
            app.LabelDatosRuta.Text = 'No se ha seleccionado ningún archivo.';
            app.RedespredeterminadasLabel_2 = uilabel(app.PanelCargarDatosClasif);
            app.RedespredeterminadasLabel_2.Text = {'Datos        '; 'predeterminados'};
            app.DatosPredetListBox = uilistbox(app.PanelCargarDatosClasif);
            app.DatosPredetListBox.Items = {'-', 'HumanActivityTest', 'PerroActivity', 'Jurinnniiini'};
            app.DatosPredetListBox.ValueChangedFcn = createCallbackFcn(app, @DatosPredetListBoxValueChanged, true);
            app.DatosPredetListBox.Value = '-';
            app.MostrarlosdatosButton = uibutton(app.PanelCargarDatosClasif, 'push');
            app.MostrarlosdatosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarlosdatosButtonPushed, true);
            app.MostrarlosdatosButton.Enable = 'off';
            app.MostrarlosdatosButton.Text = 'Mostrar los datos';
            app.PrimerPasoLabel = uilabel(app.UIFigure);
            app.PrimerPasoLabel.Text = ' Primero selecciona una red entrenada previamente:';
            app.SegundoPasoLabel = uilabel(app.UIFigure);
            app.SegundoPasoLabel.Text = 'Ahora elige el conjunto de datos que quieras clasificar:';
            app.CLASIFICARDATOSButton = uibutton(app.UIFigure, 'push');
            app.CLASIFICARDATOSButton.ButtonPushedFcn = createCallbackFcn(app, @CLASIFICARDATOSButtonPushed, true);
            app.CLASIFICARDATOSButton.Text = 'CLASIFICAR DATOS';
            app.ClasificarOKLabel = uilabel(app.UIFigure);
            app.ClasificarOKLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pantallaTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end